classdef app_one < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowsefolderButton  matlab.ui.control.Button
        ZplaneListBoxLabel  matlab.ui.control.Label
        WellDropDown        matlab.ui.control.DropDown
        WellDropDownLabel   matlab.ui.control.Label
        ZplaneListBox       matlab.ui.control.ListBox
        ImageLabel          matlab.ui.control.Label
        Image               matlab.ui.control.Image
        ErrorLabel          matlab.ui.control.Label
        PreviousButton      matlab.ui.control.Button
        NextButton          matlab.ui.control.Button
    end

    properties (Access = private)
        img_index = 1;
        folder_path = '';
        filenames = '';
        filenames_data = struct;
    end
    
    methods (Access = private)
        
        function img_formatted = format_img(app, img)
           % Image format converstion for display

           % uint16 -> uint8
            if isa(img, 'uint16')
                img_uint8 = uint8(double(img) / double(max(img(:))) * 255);     
            else
                img_uint8 = im2uint8(img); % catch-all for other datatypes
            end
            
            % grayscale -> RGB
            if size(img_uint8, 3) == 1
                img_formatted = repmat(img_uint8, [1 1 3]);
            else
                img_formatted = img_uint8;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            
            % Select folder
            app.folder_path = uigetdir('C:\Users\Kasutaja\Desktop\BSc_thesis');

            if app.folder_path == 0 % User canceled folder selection
                app.ErrorLabel.Text = 'No folder selected.';
                return;
            end
            
            app.filenames = dir(fullfile(app.folder_path, '*.tif'));
            nr_images = numel(app.filenames);
            
            % A1_03_1_1Z0_Bright Field_001.tif
            % <WellID>_<Read index>_<Channel index>_<Z index>_<Channel name>_<cycle index>.tif

            pattern = '(?<WellID>[A-Z]\d+)_(?<ReadIndex>\d+)_(?<ChannelIndex>\d+)_(?<ZIndex>\w+)_(?<ChannelName>[\w\s]+)_(?<CycleIndex>\d+)';
            app.filenames_data = struct('WellID', {}, 'ReadIndex', {}, 'ChannelIndex', {}, 'ZIndex', {}, 'WellIndex', {}, 'ZPlane', {}, 'ChannelName', {}, 'CycleIndex', {});

            for n = 1:nr_images
                f = app.filenames(n).name;
                file_data = regexp(f, pattern, 'names');
            
                zindex_str = file_data.ZIndex;
                well_index = str2double(zindex_str(1));
                z_plane = str2double(zindex_str(3));
            
                file_data.WellIndex = well_index;
                file_data.ZPlane = z_plane;
            
                app.filenames_data(n) = file_data;
            end    

            % Load in well options
            well_values = unique(cell2mat({app.filenames_data.WellIndex}));
            app.WellDropDown.Items = cellstr(string(well_values));

            % Display first image in the folder
            image1 = imread(fullfile(app.folder_path, app.filenames(1).name));
            img_formatted = app.format_img(image1);
            app.Image.ImageSource = img_formatted;

            app.ImageLabel.Text = app.filenames(1).name;
            app.ErrorLabel.Text = "";
        end

        % Value changed function: WellDropDown
        function WellDropDownValueChanged(app, event)

            % Get user chosen well data
            value = str2double(app.WellDropDown.Value);
            well_indices = [app.filenames_data.WellIndex];
            well_selected = app.filenames_data(well_indices == value);
            
            % Load in Zplane value options
            % BUG: if well = 1, then doesn't update correctly?
            zplane_values = unique([well_selected.ZPlane]);
            app.ZplaneListBox.Items = string(zplane_values);

            % For display, get first image of specified well
            %app.img_index = well_selected(1);

            %image_well_selected = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
            %img_formatted = app.format_img(image_well_selected);
    
            %app.Image.ImageSource = img_formatted;
            %app.ImageLabel.Text = app.filenames(app.img_index).name;
            
        end

        % Value changed function: ZplaneListBox
        function ZplaneListBoxValueChanged(app, event)

            % stay on the same well indexes
            % but change Z index
            
            %z_col = {app.filenames_data.ZIndex}; % Zindex values
            
            %value = app.ZplaneListBox.Value;
            %well_z = 0;
            
            %z_col_rows = z_col(app.well_indices);
            %matchingRow = app.well_indices(contains(z_col_rows, value));
            
            %if ~isempty(matchingRow)
            %    well_z = matchingRow; % match found
            %else
                % pass
            %end
            
            %app.img_index = well_z;

            %image_z = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
            %img_formatted = app.format_img(image_z);
    
            %app.Image.ImageSource = img_formatted;
            %app.ImageLabel.Text = app.filenames(app.img_index).name;

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.img_index == numel(app.filenames)

                % if user has checked all images
                % next activity

            else
                
                app.img_index = app.img_index + 1;
                app.ImageLabel.Text = app.filenames(app.img_index).name;
                app.ErrorLabel.Text = "";
                
                image_next = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.format_img(image_next);
    
                app.Image.ImageSource = img_formatted;

            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            
            if app.img_index == 1

                app.ErrorLabel.Text = "Index = 1, can't go further back";

            else
                app.img_index = app.img_index - 1;
                app.ImageLabel.Text = app.filenames(app.img_index).name;
                app.ErrorLabel.Text = "";
                
                image_prev = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.format_img(image_prev);
    
                app.Image.ImageSource = img_formatted;                           
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = ['MATLAB' newline 'App'];
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error';
            app.Image = uiimage(app.UIFigure);
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ZplaneListBox = uilistbox(app.UIFigure);
            app.ZplaneListBox.Items = {'0'};
            app.ZplaneListBox.ValueChangedFcn = createCallbackFcn(app, @ZplaneListBoxValueChanged, true);
            app.ZplaneListBox.Value = '0';
            app.WellDropDownLabel = uilabel(app.UIFigure);
            app.WellDropDownLabel.Text = 'Well';
            app.WellDropDown = uidropdown(app.UIFigure);
            app.WellDropDown.Items = {'1'};
            app.WellDropDown.ValueChangedFcn = createCallbackFcn(app, @WellDropDownValueChanged, true);
            app.WellDropDown.Value = '1';
            app.ZplaneListBoxLabel = uilabel(app.UIFigure);
            app.ZplaneListBoxLabel.Text = 'Z-plane';
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = ['Browse' newline 'folder'];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_one

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end