classdef app_two < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        YoinkButton                     matlab.ui.control.Button
        WritevaluestoresultsfileButton  matlab.ui.control.Button
        ProbabilitythresholdSlider      matlab.ui.control.Slider
        ProbabilitythresholdSliderLabel  matlab.ui.control.Label
        Binlabel                        matlab.ui.control.Label
        MLlabel                         matlab.ui.control.Label
        MLimage                         matlab.ui.control.Image
        UpdateButton                    matlab.ui.control.Button
        SelectfolderButton              matlab.ui.control.Button
        ZplaneListBoxLabel              matlab.ui.control.Label
        WellListBox                     matlab.ui.control.ListBox
        WellListBoxLabel                matlab.ui.control.Label
        ZplaneListBox                   matlab.ui.control.ListBox
        ImageLabel                      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        StateLabel                      matlab.ui.control.Label
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        RunMLmodelButton                matlab.ui.control.Button
        Binimage                        matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        folder_path = '';
        filenames_data = struct;
        analysis = table();
        output_table = table();
        img_index = 1;
    end
    
    methods (Access = private)
        
        function img_formatted = format_img(~, img)
           % Image format conversion for display

           % uint16 -> uint8
            if isa(img, 'uint16')
                img_uint8 = uint8(double(img) / double(max(img(:))) * 255);     
            else
                img_uint8 = im2uint8(img); % catch-all for other datatypes
            end
            
            % grayscale -> RGB
            if size(img_uint8, 3) == 1
                img_formatted = repmat(img_uint8, [1 1 3]);
            else
                img_formatted = img_uint8;
            end
        end
        
        % get pixel dimensions in microns
        function micronarea = pixelarea(~,metadata)

            width_pixels = XMLStringToVariable(metadata).ImageAcquisition.PixelWidth;
            height_pixels = XMLStringToVariable(metadata).ImageAcquisition.PixelWidth;
        
            width_microns = XMLStringToVariable(metadata).ImageAcquisition.ImageWidthMicrons;
            height_microns = XMLStringToVariable(metadata).ImageAcquisition.ImageHeightMicrons;
            
            S_pixels = str2double(width_pixels)*str2double(height_pixels);
            S_microns = str2double(width_microns)*str2double(height_microns);
            micronarea = S_microns/S_pixels;
            % get area of one pixel in microns(^2)
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
           
            app.folder_path = uigetdir('C:\Users\Kasutaja\Desktop\BSc_thesis');

            if app.folder_path == 0   % User canceled folder selection
                app.StateLabel.Text = 'No folder selected.';
                return;
            end
            
            % Get filenames_data, detect weird filenames

            [app.filenames_data, nonmatch_files] = dataset_handling(app.folder_path);
            if numel(nonmatch_files) > 0
                app.StateLabel.Text = ['Warning: Found ', num2str(numel(nonmatch_files)), ' files with an unexpected name.'];
                % print out faulty files?
            end

            % Load in well options
            
            wellIDs = {app.filenames_data.WellID};
            uniqueIDs = unique(wellIDs, 'stable');
            app.WellListBox.Items = cellstr(string(uniqueIDs));

            % Load in Zplane options

            zplane_values = unique(cell2mat({app.filenames_data.ZPlane}));
            app.ZplaneListBox.Items = string(zplane_values);

            % Initialize table for later excel output
            app.output_table = table('Size', [0 5], ...,
                              'VariableTypes', {'string', 'string', 'double', 'double', 'double'}, ...
                              'VariableNames', {'Filename', 'WellID', 'Area', 'Diameter', 'Perimeter'});  

            % Display first well of folder

            image1 = imread(app.filenames_data(1).FilePath);
            img_formatted = app.format_img(image1);
            app.Image.ImageSource = img_formatted;

            displayname = split(app.filenames_data(1).FilePath, filesep);
            displayname = displayname{end};
            app.ImageLabel.Text = displayname;

            UpdateButtonPushed(app, event)
        end

        % Value changed function: WellListBox
        function WellListBoxValueChanged(app, event)
            
            value = app.WellListBox.Value;
            wellIDs = {app.filenames_data.WellID};
            well_selected = app.filenames_data(strcmp(wellIDs, value));

            % Load in Zplane options for specified well
            zplane_values = unique(cell2mat({well_selected.ZPlane}));
            app.ZplaneListBox.Items = string(zplane_values);
            
            % Display first image of specified well
            img = imread(well_selected(1).FilePath);
            img_formatted = app.format_img(img);
            app.Image.ImageSource = img_formatted;

            displayname = split(well_selected(1).FilePath, filesep);
            displayname = displayname{end};
            app.ImageLabel.Text = displayname;

            UpdateButtonPushed(app, event)
        end

        % Value changed function: ZplaneListBox
        function ZplaneListBoxValueChanged(app, event)

            % Stay on the same well indexes but change Z index
            
            well_value = app.WellListBox.Value;
            wellIDs = {app.filenames_data.WellID};
            well_selected = app.filenames_data(strcmp(wellIDs, well_value));

            z_value = app.ZplaneListBox.Value;
            %z_selected = well_selected([well_selected.ZPlane] == z_value);
            
            zplanes = {well_selected.ZPlane};
            z_selected = well_selected(strcmp(zplanes, z_value));

            % Update display

            img = imread(z_selected.FilePath); % get too few or too many inputs?
            img_formatted = app.format_img(img);
            app.Image.ImageSource = img_formatted;

            displayname = split(z_selected.FilePath, filesep);
            displayname = displayname{end};

            app.ImageLabel.Text = displayname; 

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.img_index == numel(app.filenames_data)

                % if user has checked all images
                % next activity

            else
                
                app.img_index = app.img_index + 1;
                app.ImageLabel.Text = app.filenames_data(app.img_index).name;
                app.StateLabel.Text = "";
                
                image_next = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.format_img(image_next);
    
                app.Image.ImageSource = img_formatted;

            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            
            if app.img_index == 1

                app.StateLabel.Text = "Index = 1, can't go further back";

            else
                app.img_index = app.img_index - 1;
                app.ImageLabel.Text = app.filenames(app.img_index).name;
                app.StateLabel.Text = "";
                
                image_prev = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.format_img(image_prev);
    
                app.Image.ImageSource = img_formatted;                           
            end
        end

        % Button pushed function: RunMLmodelButton
        function RunMLmodelButtonPushed(app, event)

            % Later: make choosing Ilastik ML model dynamic
            ilastik_proj_path = 'C:\Users\Kasutaja\Desktop\BSc_thesis\Sphera1.ilp';

%             To-Fix: if no folder chosen, continues to run and causes errors later?
%             if ~isempty(app.folder_path)
%                 run_ilastik(app.folder_path, ilastik_proj_path)
%             else
%                 app.StateLabel.Text = "Choose a folder to analyse";
%             end

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Processing', 'Message', 'Starting Ilastik...');
            run_ilastik(app.folder_path, ilastik_proj_path, progress)
            app.StateLabel.Text = "Images analysed with ML.";

            %app.MLimage.ImageSource = imread(fullfile(app.folder_path,
            %'_Probabilities')); %path of new folder

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            % Display the corresponding h5 prob file to original image
           
            probfiles = [fullfile(app.folder_path), '_Probabilities'];
            h5 = dir(fullfile(probfiles, '*.h5'));
            h5names = {h5.name};
           
            % matching:
            % label = 'A4_03_1_1Z3_Bright Field_001.tif';
            % h5names(5) = 'A4_03_1_1Z3_Bright Field_001_Probabilities.h5';
            
            [~, labelname, ~] = fileparts(app.ImageLabel.Text); % [path, name, extension]
                        
            for n = 1:length(h5names)
                if contains(h5names(n), labelname) % Check if h5name contains label
                    h5_selected = h5names(n);
                    index = n; % Record
                end
            end
            
            disp(index)

            h5_path = fullfile((probfiles), h5_selected);
            data = h5read(h5_path{1}, '/exported_data');
            show_class = 1; % spheroid = 1, background = 2
            show = permute(data,[3,2,1]); % cxy to yxc
            spheroid_probmap = show(:, :, show_class);
            
            threshold = app.ProbabilitythresholdSlider.Value / 100;
            binaryimage = spheroid_probmap >= threshold;
            spheroid_pixels = sum(spheroid_probmap(:) >= threshold);
            
            %img_path = "C:\Users\Kasutaja\Desktop\BSc_thesis\210816_100259_Plate 1_mini\A1_03_1_1Z0_Bright Field_001.tif";
            img_path = [fullfile(app.folder_path), '\',app.ImageLabel.Text];

            metadata = imfinfo(img_path).ImageDescription;
            spheroid_area = spheroid_pixels * pixelarea(app, metadata);
            
            % Onto the graph with cicumference + diameter:
            % Fill holes in the binary image
            binary_image_filled = imfill(binaryimage, 'holes');        
            imshow(binary_image_filled, 'Parent', app.UIAxes);

            app.UIAxes.XColor = 'none';  
            app.UIAxes.YColor = 'none';  % Remove axis ticks and labels
            app.UIAxes.Color = 'none';   % Transparent background
            app.UIAxes.XLim = [1 size(binaryimage, 2)];
            app.UIAxes.YLim = [1 size(binaryimage, 1)];
            app.UIAxes.YDir = 'reverse'; % to match image coordinates
            hold(app.UIAxes, 'on');
            
            % Compute the convex hull + extract boundaries
            convex_hull = bwconvhull(binary_image_filled);                        % Compute the convex hull
            boundaries = bwboundaries(convex_hull);                               % Extract boundaries of the convex hull
            boundary = boundaries{1};                                             % Get the first boundary (outermost boundary)
            
            x = boundary(:, 2);  % Col 2: x-coordinates
            y = boundary(:, 1);  % Col 1: y-coordinates
            plot(app.UIAxes, x, y, 'r-', 'LineWidth', 1); % Convex hull outline

            distances = pdist2(boundary, boundary);  % Pairwise distances between all boundary points
            bound_dist = sqrt(diff(x).^2 + diff(y).^2); % Distances between consecutive boundary points
            perimeter = sum(bound_dist);
            perimeter_microns = perimeter * sqrt(pixelarea(app, metadata));
            
            % Boundary points furthest apart
            [~, max_idx] = max(distances(:));
            [max_row, max_col] = ind2sub(size(distances), max_idx);

            diameter = distances(max_row, max_col); %length of the diameter;
            diameter_microns = diameter * sqrt(pixelarea(app, metadata));
          
            plot(app.UIAxes, [boundary(max_row, 2), boundary(max_col, 2)], ...
                             [boundary(max_row, 1), boundary(max_col, 1)], ...
                             'g-', 'LineWidth', 2);
            
            hold(app.UIAxes, 'off');

            % Write everything to central database
            % num2str(spheroid_pixels), num2str(spheroid_area), num2str(diameter), num2str(diameter_microns), num2str(perimeter), num2str(perimeter_microns)];   
            
            % analysis = tablerow of current spheroid with custom thresh
            app.analysis = table(string(labelname), string(app.filenames_data(index).WellID), spheroid_pixels, diameter, perimeter, ...
                               'VariableNames', {'Filename', 'WellID', 'Area', 'Diameter', 'Perimeter'});

            % Show everything
            app.StateLabel.Text = ['Spheroid area (pixels): ', num2str(spheroid_pixels), newline, ...
                                   'Spheroid area (µm^2): ', num2str(spheroid_area), newline, ...
                                   'Diameter (pixels): ', num2str(diameter), newline, ...
                                   'Diameter (µm): ', num2str(diameter_microns), newline, ...
                                   'Perimeter (pixels): ', num2str(perimeter), newline, ...
                                   'Perimeter (µm): ', num2str(perimeter_microns)];
            app.MLimage.ImageSource = app.format_img(spheroid_probmap);
            app.Binimage.ImageSource = app.format_img(binaryimage);
            
            % connected component/concomp analysis
            CC = bwconncomp(binaryimage);
            stats = regionprops(CC, 'Area');      % get area of each component
            [~, largest_idx] = max([stats.Area]); % index of the largest connected component
            largest_object_indices = CC.PixelIdxList{largest_idx};
            
            largest_object_mask = zeros(size(spheroid_probmap));
            largest_object_mask(largest_object_indices) = 1;
            largest_object_mask = imfill(largest_object_mask, 'holes');            
            masked_spheroid = spheroid_probmap(largest_object_mask == 1);

            [x,f] = ksdensity(masked_spheroid);
            plot(app.UIAxes1, f, x/100);
            %set(app.UIAxes1, 'YScale', 'log');
            title(app.UIAxes1, 'PDF of spheroid');
            xlabel(app.UIAxes1, 'Pixel Intensity');
            ylabel(app.UIAxes1, '%');
        end

        % Value changed function: ProbabilitythresholdSlider
        function ProbabilitythresholdSliderValueChanged(app, event)
            value = app.ProbabilitythresholdSlider.Value;
            UpdateButtonPushed(app, event)
        end

        % Button pushed function: WritevaluestoresultsfileButton
        function WritevaluestoresultsfileButtonPushed(app, event)
           
            app.output_table = [app.output_table; app.analysis];
            disp(app.output_table)

        end

        % Button pushed function: YoinkButton
        function YoinkButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Binimage = uiimage(app.UIFigure);
            app.RunMLmodelButton = uibutton(app.UIFigure, 'push');
            app.RunMLmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunMLmodelButtonPushed, true);
            app.RunMLmodelButton.Text = 'Run ML model';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State';
            app.Image = uiimage(app.UIFigure);
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ZplaneListBox = uilistbox(app.UIFigure);
            app.ZplaneListBox.Items = {'0'};
            app.ZplaneListBox.ValueChangedFcn = createCallbackFcn(app, @ZplaneListBoxValueChanged, true);
            app.ZplaneListBox.Value = '0';
            app.WellListBoxLabel = uilabel(app.UIFigure);
            app.WellListBoxLabel.Text = 'Well';
            app.WellListBox = uilistbox(app.UIFigure);
            app.WellListBox.Items = {'1'};
            app.WellListBox.ValueChangedFcn = createCallbackFcn(app, @WellListBoxValueChanged, true);
            app.WellListBox.Value = '1';
            app.ZplaneListBoxLabel = uilabel(app.UIFigure);
            app.ZplaneListBoxLabel.Text = 'Z-plane';
            app.SelectfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.MLimage = uiimage(app.UIFigure);
            app.MLlabel = uilabel(app.UIFigure);
            app.MLlabel.Text = 'Probability map';
            app.Binlabel = uilabel(app.UIFigure);
            app.Binlabel.Text = 'Binarized';
            app.ProbabilitythresholdSliderLabel = uilabel(app.UIFigure);
            app.ProbabilitythresholdSliderLabel.Text = 'Probability threshold (%)';
            app.ProbabilitythresholdSlider = uislider(app.UIFigure);
            app.ProbabilitythresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ProbabilitythresholdSliderValueChanged, true);
            app.WritevaluestoresultsfileButton = uibutton(app.UIFigure, 'push');
            app.WritevaluestoresultsfileButton.ButtonPushedFcn = createCallbackFcn(app, @WritevaluestoresultsfileButtonPushed, true);
            app.WritevaluestoresultsfileButton.Text = 'Write values to results file';
            app.YoinkButton = uibutton(app.UIFigure, 'push');
            app.YoinkButton.ButtonPushedFcn = createCallbackFcn(app, @YoinkButtonPushed, true);
            app.YoinkButton.Text = 'Yoink';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_two

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end